classdef Automatas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LmiteIzquierdoEditFieldLabel   matlab.ui.control.Label
        LmiteIzquierdoEditField        matlab.ui.control.NumericEditField
        LmiteDerechoEditFieldLabel     matlab.ui.control.Label
        LmiteDerechoEditField          matlab.ui.control.NumericEditField
        PosicinObjetivoIncrementalCheckBox  matlab.ui.control.CheckBox
        PosicinObjetivoEditFieldLabel  matlab.ui.control.Label
        PosicinObjetivoEditField       matlab.ui.control.NumericEditField
        LmiteInferiorBarcoEditFieldLabel  matlab.ui.control.Label
        LmiteInferiorBarcoEditField    matlab.ui.control.NumericEditField
        AlturaMuelleEditFieldLabel     matlab.ui.control.Label
        AlturaMuelleEditField          matlab.ui.control.NumericEditField
        AlturaCarroEditFieldLabel      matlab.ui.control.Label
        AlturaCarroEditField           matlab.ui.control.NumericEditField
        AlturaVigaTesteraEditFieldLabel  matlab.ui.control.Label
        AlturaVigaTesteraEditField     matlab.ui.control.NumericEditField
        AlturaManualMuelleEditFieldLabel  matlab.ui.control.Label
        AlturaManualMuelleEditField    matlab.ui.control.NumericEditField
        AlturaManualBarcoEditFieldLabel  matlab.ui.control.Label
        AlturaManualBarcoEditField     matlab.ui.control.NumericEditField
        CONFIGURACINLabel              matlab.ui.control.Label
        LMITESCARROLabel               matlab.ui.control.Label
        POSICININICIALLabel            matlab.ui.control.Label
        LMITESIZAJELabel               matlab.ui.control.Label
        ALTURACONTROLMANUALLabel       matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
    end

    methods (Access = private)

        % Callback function
        function StartButtonValueChanged(app, event)
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            assignin('base','x_izq',app.LmiteIzquierdoEditField.Value);
            assignin('base','x_der',app.LmiteDerechoEditField.Value);
            assignin('base','PObjetivo_incremental',app.PosicinObjetivoIncrementalCheckBox.Value);
            assignin('base','PObjetivo_fijo',app.PosicinObjetivoEditField.Value);
            assignin('base','Y_inf_barco',app.LmiteInferiorBarcoEditField.Value);
            assignin('base','Y_muelle',app.AlturaMuelleEditField.Value);
            assignin('base','Y_t0',app.AlturaCarroEditField.Value);
            assignin('base','Y_sb',app.AlturaVigaTesteraEditField.Value);
            assignin('base','AlturaInicialMuelle',app.AlturaManualMuelleEditField.Value);
            assignin('base','AlturaInicialBarco',app.AlturaManualBarcoEditField.Value);
            assignin('base','Y_max',40);
            evalin('base','Variables');
            Automata;
            set_param('Automata','SimulationCommand','start');
%             simout=sim('Automata');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('Automata','SimulationCommand','stop');            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LmiteIzquierdoEditFieldLabel = uilabel(app.UIFigure);
            app.LmiteIzquierdoEditFieldLabel.Text = 'Límite Izquierdo ';
            app.LmiteIzquierdoEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmiteIzquierdoEditField.Value = -30;
            app.LmiteDerechoEditFieldLabel = uilabel(app.UIFigure);
            app.LmiteDerechoEditFieldLabel.Text = 'Límite Derecho';
            app.LmiteDerechoEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmiteDerechoEditField.Value = 50;
            app.PosicinObjetivoIncrementalCheckBox = uicheckbox(app.UIFigure);
            app.PosicinObjetivoIncrementalCheckBox.Text = 'Posición Objetivo Incremental';
            app.PosicinObjetivoEditFieldLabel = uilabel(app.UIFigure);
            app.PosicinObjetivoEditFieldLabel.Text = 'Posición Objetivo';
            app.PosicinObjetivoEditField = uieditfield(app.UIFigure, 'numeric');
            app.PosicinObjetivoEditField.Value = -30;
            app.LmiteInferiorBarcoEditFieldLabel = uilabel(app.UIFigure);
            app.LmiteInferiorBarcoEditFieldLabel.Text = 'Límite Inferior Barco';
            app.LmiteInferiorBarcoEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmiteInferiorBarcoEditField.Value = -20;
            app.AlturaMuelleEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaMuelleEditFieldLabel.Text = 'Altura Muelle';
            app.AlturaMuelleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaCarroEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaCarroEditFieldLabel.Text = 'Altura Carro';
            app.AlturaCarroEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaCarroEditField.Value = 45;
            app.AlturaVigaTesteraEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaVigaTesteraEditFieldLabel.Text = 'Altura Viga Testera';
            app.AlturaVigaTesteraEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaVigaTesteraEditField.Value = 15;
            app.AlturaManualMuelleEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaManualMuelleEditFieldLabel.Text = 'Altura Manual Muelle';
            app.AlturaManualMuelleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaManualMuelleEditField.Value = 2.5;
            app.AlturaManualBarcoEditFieldLabel = uilabel(app.UIFigure);
            app.AlturaManualBarcoEditFieldLabel.Text = 'Altura Manual Barco';
            app.AlturaManualBarcoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlturaManualBarcoEditField.Value = 2.5;
            app.CONFIGURACINLabel = uilabel(app.UIFigure);
            app.CONFIGURACINLabel.Text = 'CONFIGURACIÓN';
            app.LMITESCARROLabel = uilabel(app.UIFigure);
            app.LMITESCARROLabel.Text = 'LÍMITES CARRO';
            app.POSICININICIALLabel = uilabel(app.UIFigure);
            app.POSICININICIALLabel.Text = 'POSICIÓN INICIAL';
            app.LMITESIZAJELabel = uilabel(app.UIFigure);
            app.LMITESIZAJELabel.Text = 'LÍMITES IZAJE';
            app.ALTURACONTROLMANUALLabel = uilabel(app.UIFigure);
            app.ALTURACONTROLMANUALLabel.Text = 'ALTURA CONTROL MANUAL';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Automatas

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end